classdef maingui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OvalocytesDetectionUIFigure  matlab.ui.Figure
        OvalocytesDetectionUsingImageProcessingLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        LoadSamplesTab               matlab.ui.container.Tab
        LoadBloodSamplePanel         matlab.ui.container.Panel
        ImportSampleButton           matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        ClearAllButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ProcessSampleTab             matlab.ui.container.Tab
        Panel_2                      matlab.ui.container.Panel
        ProcessSampleButton          matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        ResultsTab                   matlab.ui.container.Tab
        DetectedOvalocytesPanel      matlab.ui.container.Panel
        OutputParametersPanel        matlab.ui.container.Panel
        NormalCellEstimatedLabel     matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        ElongatedCellEstimatedLabel  matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        ResetAllButton               matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        ExportDataButton             matlab.ui.control.Button
        DetectOvalocytesButton       matlab.ui.control.Button
        ExitButton_2                 matlab.ui.control.Button
        AboutTab                     matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Input; % Description
        erodeI;
        erodedI;
        a;
        b;
        c;
        d;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportSampleButton
        function ImportSampleButtonPushed(app, event)
        [FileName, PathName]=uigetfile('*.jpg','Select an Blood sample');
        app.Input=imread(strcat(PathName,FileName));
        imshow(app.Input,'parent',app.UIAxes);
        title('Blood Sample','parent',app.UIAxes);
        app.a=1;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)

        end

        % Button pushed function: ProcessSampleButton
        function ProcessSampleButtonPushed(app, event)
        if app.a ==1
            I1 = rgb2gray(app.Input);
            I2 = adapthisteq(I1);
            
            BW = imbinarize(I2,graythresh(I2));
            BW1 = bwareaopen(BW, 1000);
            BW2 = ~BW1;
            
            BW3 = imfill(BW2, 'holes');
            BW4 = bwareaopen(BW3, 400);
            
            se = strel('disk',5);
            app.erodedI = imerode(BW4,se);
            imshow(app.erodedI,'parent',app.UIAxes2);
            title('Preprocessed Blood Sample','Parent',app.UIAxes2);
            
            subplot(2,2,1);
            imshow(I1);title('Gray Image');
            subplot(2,2,2);
            imshow(I2);title('Contrast enhancement');
            subplot(2,2,3);
            imshow(BW1);title('Small Object Removal');
            subplot(2,2,4);
            imshow(app.erodedI);title('Inverted Binary Image');
            app.b=1;
        else
            msgbox('Import Blood Sample','Error','error');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OvalocytesDetectionUIFigure = uifigure('Visible', 'off');
            app.OvalocytesDetectionUIFigure.Name = 'Ovalocytes Detection';
            app.OvalocytesDetectionUsingImageProcessingLabel = uilabel(app.OvalocytesDetectionUIFigure);
            app.OvalocytesDetectionUsingImageProcessingLabel.Text = 'Ovalocytes Detection Using Image Processing';
            app.TabGroup = uitabgroup(app.OvalocytesDetectionUIFigure);
            app.LoadSamplesTab = uitab(app.TabGroup);
            app.LoadSamplesTab.Title = 'Load Samples';
            app.LoadBloodSamplePanel = uipanel(app.LoadSamplesTab);
            app.LoadBloodSamplePanel.Title = 'Load Blood Sample';
            app.ImportSampleButton = uibutton(app.LoadBloodSamplePanel, 'push');
            app.ImportSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSampleButtonPushed, true);
            app.ImportSampleButton.Text = 'Import Sample';
            app.ExitButton = uibutton(app.LoadBloodSamplePanel, 'push');
            app.ExitButton.Text = 'Exit';
            app.ClearAllButton = uibutton(app.LoadBloodSamplePanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.UIAxes = uiaxes(app.LoadSamplesTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.ProcessSampleTab = uitab(app.TabGroup);
            app.ProcessSampleTab.Title = 'Process Sample';
            app.Panel_2 = uipanel(app.ProcessSampleTab);
            app.ProcessSampleButton = uibutton(app.Panel_2, 'push');
            app.ProcessSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSampleButtonPushed, true);
            app.ProcessSampleButton.Text = 'Process Sample';
            app.UIAxes2 = uiaxes(app.ProcessSampleTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.DetectedOvalocytesPanel = uipanel(app.ResultsTab);
            app.DetectedOvalocytesPanel.Title = 'Detected Ovalocytes';
            app.OutputParametersPanel = uipanel(app.ResultsTab);
            app.OutputParametersPanel.Title = 'Output Parameters';
            app.NormalCellEstimatedLabel = uilabel(app.OutputParametersPanel);
            app.NormalCellEstimatedLabel.Text = 'Normal Cell Estimated';
            app.EditField = uieditfield(app.OutputParametersPanel, 'numeric');
            app.ElongatedCellEstimatedLabel = uilabel(app.OutputParametersPanel);
            app.ElongatedCellEstimatedLabel.Text = 'Elongated Cell Estimated';
            app.EditField_2 = uieditfield(app.OutputParametersPanel, 'numeric');
            app.ResetAllButton = uibutton(app.OutputParametersPanel, 'push');
            app.ResetAllButton.Text = 'Reset All';
            app.Panel = uipanel(app.ResultsTab);
            app.ExportDataButton = uibutton(app.Panel, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.DetectOvalocytesButton = uibutton(app.Panel, 'push');
            app.DetectOvalocytesButton.Text = 'Detect Ovalocytes';
            app.ExitButton_2 = uibutton(app.Panel, 'push');
            app.ExitButton_2.Text = 'Exit';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Label = uilabel(app.AboutTab);
            % Show the figure after all components are created
            app.OvalocytesDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maingui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OvalocytesDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OvalocytesDetectionUIFigure)
        end
    end
end